classdef estimationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Button_2          matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        Button_4          matlab.ui.control.Button
        Panel             matlab.ui.container.Panel
        Label_2           matlab.ui.control.Label
        EditField_2       matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
        EditField_4Label  matlab.ui.control.Label
        EditField_4       matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        EditField_5       matlab.ui.control.NumericEditField
        Button_5          matlab.ui.control.Button
        Panel_2           matlab.ui.container.Panel
        MDLPanel          matlab.ui.container.Panel
        Button_3          matlab.ui.control.Button
        MDLLabel          matlab.ui.control.Label
        Label_7           matlab.ui.control.Label
        AICPanel          matlab.ui.container.Panel
        Button_6          matlab.ui.control.Button
        AICLabel          matlab.ui.control.Label
        Label_8           matlab.ui.control.Label
        GDEPanel          matlab.ui.container.Panel
        Button_7          matlab.ui.control.Button
        GDELabel          matlab.ui.control.Label
        Label_9           matlab.ui.control.Label
        NBICPanel         matlab.ui.container.Panel
        Button_8          matlab.ui.control.Button
        NBICLabel         matlab.ui.control.Label
        Label_10          matlab.ui.control.Label
        ISSMPanel         matlab.ui.container.Panel
        Button_9          matlab.ui.control.Button
        ISSMLabel         matlab.ui.control.Label
        Label_11          matlab.ui.control.Label
        LDFCMPanel        matlab.ui.container.Panel
        Button_10         matlab.ui.control.Button
        LDFCMLabel        matlab.ui.control.Label
        Label_12          matlab.ui.control.Label
        MSTDCPanel        matlab.ui.container.Panel
        Button_11         matlab.ui.control.Button
        MSTDCLabel        matlab.ui.control.Label
        Label_13          matlab.ui.control.Label
        MSRSEPanel        matlab.ui.container.Panel
        Button_12         matlab.ui.control.Button
        MSRSELabel        matlab.ui.control.Label
        Label_14          matlab.ui.control.Label
        Button_13         matlab.ui.control.Button
        Label_16          matlab.ui.control.Label
        Label_17          matlab.ui.control.Label
        Label_15          matlab.ui.control.Label
        Button            matlab.ui.control.Button
        Label_6           matlab.ui.control.Label
    end


    properties (Access = private)
        signal % 输入信号
        L      % 快拍数
        M      % 阵元数
        num    % 信源数
        snr    % 信噪比
        s_jam      % 阵列流型
        num_max    % 最大能估计的信源数
        Ns_AIC     % AIC 估计结果
        Ns_MDL     % MDL 估计结果
        Ns_GDE     % GDE 估计结果
        Ns_NBIC    % NBIC 估计结果
        Ns_ISSM    % ISSM 估计结果
        Ns_LDFCM   % LDFCM 估计结果
        Ns_MSTDC   % MSTDC 估计结果
        Ns_MSRSE   % MSRSE 估计结果
    end


    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file, path] = uigetfile({'*.mat';'*.xlsx'}, 'Select Data File'); % 选择文件
            disp(file);
            if isequal(file, file)
                disp(['用户选择', fullfile(path, file)]);
                % 可以在这里处理文件，比如读取数据
                app.signal = load(file);
                app.signal = app.signal.X;
                [app.M, app.L] = size(app.signal);
                app.EditField_4.Value = app.M;
                app.EditField_3.Value = app.L;
                msgbox('载入数据成功！', '提示');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            f0 = 15.48e4; % 观测信号f0 = 15.48e4;
            fs = 62e4;
            fa = 2.3e4;
            fb = 2.2e4;
            x1 = zeros(app.num, app.L);
            for i=1:app.num
                [~,~,~,x1(i,:)]=narrow_signal(fs,app.L,fa,fb,f0);
            end
            Am=10^(app.snr/10);
            X=Am*x1;
            A=app.s_jam(1:app.num,:);%方向矩阵；
            A=A';
            A1=A*X; 
            app.signal=awgn(A1, app.snr, 'measured');
            app.Label_6.Text = "生成观测信号成功";
            pause(2);
            app.Label_6.Text = "";
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            R=app.signal*app.signal'/app.L; %信号协方差
            [~,v]=svd(R);
            T=diag(v);
            [~, app.Ns_MDL] = func_MDL(app.M,app.L,T);
            app.Label_7.Text = num2str(app.Ns_MDL);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            Array_Num=8;% 阵元数
            app.M=Array_Num;
            app.L = 256;
            app.num = 4;
            app.snr = 5;
            app.EditField_4.Value = app.M;
            app.EditField_2.Value = app.num;
            app.EditField_3.Value = app.L;
            app.EditField_5.Value = app.snr;
            d=0.5; %线阵半径
            lamda=1; %波长
            kk=6;    %线阵
            app.num_max = 6;
            % 入射角
            theta_jam=10:15:app.num_max*20;
            degrad=pi/180;
            %方位角
            alfa_jam=10:20:app.num_max*20;
            app.s_jam = zeros(app.num_max, app.M);
            for i=1:app.num_max
                app.s_jam(i,:)=array_form(app.M, d, lamda, theta_jam(i), alfa_jam(i), kk);
            end
            f0 = 15.48e4; % 观测信号f0 = 15.48e4;
            fs = 62e4;
            fa = 2.3e4;
            fb = 2.2e4;
            x1 = zeros(app.num, app.L);
            for i=1:app.num
                [~,~,~,x1(i,:)]=narrow_signal(fs,app.L,fa,fb,f0);
            end
            Am=10^(app.snr/10);
            X=Am*x1;
            A=app.s_jam(1:app.num,:);%方向矩阵；
            A=A';
            A1=A*X; 
            app.signal=awgn(A1, app.snr, 'measured');
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.M = value;
            d=0.5; %线阵半径
            lamda=1; %波长
            kk=6;    %线阵
            app.num_max = 6;
            % 入射角
            theta_jam=10:15:app.num_max*20;
            degrad=pi/180;
            %方位角
            alfa_jam=10:20:app.num_max*20;
            app.s_jam = zeros(app.num_max, app.M);
            for i=1:app.num_max
                app.s_jam(i,:)=array_form(app.M, d, lamda, theta_jam(i), alfa_jam(i), kk);
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.num = value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.L = value;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.snr = value;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            t = 1:app.L;
            plot(app.UIAxes, t, real(app.signal(1,:)));
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            R=app.signal*app.signal'/app.L; %信号协方差
            [~,v]=svd(R);
            T=diag(v);
            [~, app.Ns_AIC] = func_AIC(app.M,app.L,T);
            app.Label_8.Text = num2str(app.Ns_AIC);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            R=app.signal*app.signal'/app.L; %信号协方差
            [~,app.Ns_GDE] = func_GDE(app.M, app.L, R);
            app.Label_9.Text = num2str(app.Ns_GDE);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            R=app.signal*app.signal'/app.L; %信号协方差
            [~,app.Ns_NBIC] = func_NBIC(1/(app.M*app.L), app.M, app.L, R);
            app.Label_10.Text = num2str(app.Ns_NBIC);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            [~,app.Ns_ISSM]=func_ISSM(app.signal);
            app.Label_11.Text = num2str(app.Ns_ISSM);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            R=app.signal*app.signal'/app.L; %信号协方差
            [app.Ns_LDFCM]=func_LDFCM(R);
            app.Label_12.Text = num2str(app.Ns_LDFCM);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            [~,app.Ns_MSTDC] = func_MSTDC(app.signal, app.M, app.L);
            app.Label_13.Text = num2str(app.Ns_MSTDC);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            % 稀疏表示参数
            param.L = 3;
            param.K = 45;
            param.numIteration = 50;
            param.errorFlag = 1; 
            param.errorGoal = 1e-8;
            param.preserveDCAtom = 0;
            % Dictionary = randn(L,param.K);
            param.InitializationMethod = 'DataElements';
            param.displayProgress = 0;
            filename = strcat('./dictionaries/white/dictionary_white_sensor_', num2str(app.M), '.mat');
            Dictionary_base = load(filename);
            Dictionary_base = Dictionary_base.Dictionary_base;
            [~,app.Ns_MSRSE] = func_MSRSE(app.L, Dictionary_base, app.num_max, app.signal, param.L);
            app.Label_14.Text = num2str(app.Ns_MSRSE);
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            Button_6Pushed(app, event);
            Button_3Pushed(app, event);
            Button_7Pushed(app, event);
            Button_8Pushed(app, event);
            Button_9Pushed(app, event);
            Button_10Pushed(app, event);
            Button_11Pushed(app, event);
            Button_12Pushed(app, event);
            result = zeros(1, 8);
            result(1) = app.Ns_AIC;
            result(2) = app.Ns_MDL;
            result(3) = app.Ns_GDE;
            result(4) = app.Ns_NBIC;
            result(5) = app.Ns_ISSM;
            result(6) = app.Ns_LDFCM;
            result(7) = app.Ns_MSTDC;
            result(8) = app.Ns_MSRSE;
            app.Label_17.Text = num2str(max(result));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '载入观测信号';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '观测信号时域图')
            xlabel(app.UIAxes, '信号点')
            ylabel(app.UIAxes, '信号幅度')
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '初始化';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '观测信号参数';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '信源数';
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '快拍数';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4Label = uilabel(app.Panel);
            app.EditField_4Label.Text = '阵元数';
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '信噪比';
            app.EditField_5 = uieditfield(app.Panel, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '显示观测信号';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '各种估计方法';
            app.MDLPanel = uipanel(app.Panel_2);
            app.MDLPanel.Title = 'MDL';
            app.Button_3 = uibutton(app.MDLPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '估计';
            app.MDLLabel = uilabel(app.MDLPanel);
            app.MDLLabel.Text = 'MDL估计出的结果：';
            app.Label_7 = uilabel(app.MDLPanel);
            app.Label_7.Text = '';
            app.AICPanel = uipanel(app.Panel_2);
            app.AICPanel.Title = 'AIC';
            app.Button_6 = uibutton(app.AICPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '估计';
            app.AICLabel = uilabel(app.AICPanel);
            app.AICLabel.Text = 'AIC估计出的结果：';
            app.Label_8 = uilabel(app.AICPanel);
            app.Label_8.Text = '';
            app.GDEPanel = uipanel(app.Panel_2);
            app.GDEPanel.Title = 'GDE';
            app.Button_7 = uibutton(app.GDEPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '估计';
            app.GDELabel = uilabel(app.GDEPanel);
            app.GDELabel.Text = 'GDE估计出的结果：';
            app.Label_9 = uilabel(app.GDEPanel);
            app.Label_9.Text = '';
            app.NBICPanel = uipanel(app.Panel_2);
            app.NBICPanel.Title = 'NBIC';
            app.Button_8 = uibutton(app.NBICPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '估计';
            app.NBICLabel = uilabel(app.NBICPanel);
            app.NBICLabel.Text = 'NBIC估计出的结果：';
            app.Label_10 = uilabel(app.NBICPanel);
            app.Label_10.Text = '';
            app.ISSMPanel = uipanel(app.Panel_2);
            app.ISSMPanel.Title = 'ISSM';
            app.Button_9 = uibutton(app.ISSMPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '估计';
            app.ISSMLabel = uilabel(app.ISSMPanel);
            app.ISSMLabel.Text = 'ISSM估计出的结果：';
            app.Label_11 = uilabel(app.ISSMPanel);
            app.Label_11.Text = '';
            app.LDFCMPanel = uipanel(app.Panel_2);
            app.LDFCMPanel.Title = 'LDFCM';
            app.Button_10 = uibutton(app.LDFCMPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '估计';
            app.LDFCMLabel = uilabel(app.LDFCMPanel);
            app.LDFCMLabel.Text = 'LDFCM估计出的结果：';
            app.Label_12 = uilabel(app.LDFCMPanel);
            app.Label_12.Text = '';
            app.MSTDCPanel = uipanel(app.Panel_2);
            app.MSTDCPanel.Title = 'MSTDC';
            app.Button_11 = uibutton(app.MSTDCPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '估计';
            app.MSTDCLabel = uilabel(app.MSTDCPanel);
            app.MSTDCLabel.Text = 'MSTDC估计出的结果：';
            app.Label_13 = uilabel(app.MSTDCPanel);
            app.Label_13.Text = '';
            app.MSRSEPanel = uipanel(app.Panel_2);
            app.MSRSEPanel.Title = 'MSRSE';
            app.Button_12 = uibutton(app.MSRSEPanel, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '估计';
            app.MSRSELabel = uilabel(app.MSRSEPanel);
            app.MSRSELabel.Text = 'MSRSE估计出的结果：';
            app.Label_14 = uilabel(app.MSRSEPanel);
            app.Label_14.Text = '';
            app.Button_13 = uibutton(app.Panel_2, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '全部估计';
            app.Label_16 = uilabel(app.Panel_2);
            app.Label_16.Text = '结合各方法估计出的结果：';
            app.Label_17 = uilabel(app.Panel_2);
            app.Label_17.Text = '';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '阵列信号信源数估计软件';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '生成观测信号';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = estimationApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end